classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StepbackButton       matlab.ui.control.Button
        AnnotEditField       matlab.ui.control.EditField
        AnnotEditFieldLabel  matlab.ui.control.Label
        LogButton            matlab.ui.control.Button
        StepforwardButton    matlab.ui.control.Button
        PauseButton          matlab.ui.control.Button
        PlayButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Variables for animation and annotations
    properties (Access = private)
        t % Time vector
        data % Animation data (multiple time series)
        frame % Current frame
        annotations % Store annotations
        isPaused % Flag to control pause/play
    end
    methods (Access = private)
        function initializeData(app)
            app.t = linspace(0, 10, 100); % Time vector
            app.data = sin(2*pi*app.t) + 0.5 * randn(1, 100); % Example sine wave with noise
            app.frame = 1; % Start at frame 1
            app.annotations = {}; % Initialize annotations
            app.isPaused = false; % Start with animation running
        end
        function updatePlot(app)
            plot(app.UIAxes, app.t(1:app.frame), app.data(1:app.frame), 'b', 'LineWidth', 2);
            hold(app.UIAxes, 'on');

            % Display annotations for the current frame
            for i = 1:length(app.annotations)
                annotation = app.annotations{i};
                if annotation.frame == app.frame
                    text(app.UIAxes, annotation.time, annotation.value, annotation.text, ...
                        'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'Color', 'red');
                end
            end
            hold(app.UIAxes, 'off');
            xlim(app.UIAxes, [0, 10]);
            ylim(app.UIAxes, [-2, 2]);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Value');
        end
        % Function to start or resume the animation
        function startAnimation(app)
            app.isPaused = false; % Set to play
            while app.frame <= length(app.t) && ~app.isPaused
                app.updatePlot();
                app.frame = app.frame + 1;
                pause(0.05); % Adjust speed of animation
            end
        end
        % Function to pause the animation
        function pauseAnimation(app)
            app.isPaused = true; % Pause the animation
        end
        % Function to step forward one frame
        function stepForward(app)
            if app.frame < length(app.t)
                app.frame = app.frame + 1;
                app.updatePlot();
            end
        end
                % Function to step back one frame
        function stepBack(app)
            if app.frame < length(app.t)
                app.frame = app.frame - 1;
                app.updatePlot();
            end
        end
        function annotateCurrentFrame(app)
            annotationText = app.AnnotEditField;
            if ~isempty(annotationText)
                % Store annotation with the current frame, time, and value
                newAnnotation.frame = app.frame;
                newAnnotation.time = app.t(app.frame);
                newAnnotation.value = app.data(app.frame);
                newAnnotation.text = annotationText.Value;
                app.annotations{end+1} = newAnnotation;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeData(app)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            startAnimation(app)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pauseAnimation(app)
        end

        % Button pushed function: StepforwardButton
        function StepforwardButtonPushed(app, event)
            stepForward(app)
        end

        % Button pushed function: LogButton
        function LogButtonPushed(app, event)
            annotateCurrentFrame(app)
        end

        % Button pushed function: StepbackButton
        function StepbackButtonPushed(app, event)
            stepBack(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StepforwardButton = uibutton(app.UIFigure, 'push');
            app.StepforwardButton.ButtonPushedFcn = createCallbackFcn(app, @StepforwardButtonPushed, true);
            app.StepforwardButton.Text = 'Step forward';
            app.LogButton = uibutton(app.UIFigure, 'push');
            app.LogButton.ButtonPushedFcn = createCallbackFcn(app, @LogButtonPushed, true);
            app.LogButton.Text = 'Log';
            app.AnnotEditFieldLabel = uilabel(app.UIFigure);
            app.AnnotEditFieldLabel.Text = 'Annot';
            app.AnnotEditField = uieditfield(app.UIFigure, 'text');
            app.StepbackButton = uibutton(app.UIFigure, 'push');
            app.StepbackButton.ButtonPushedFcn = createCallbackFcn(app, @StepbackButtonPushed, true);
            app.StepbackButton.Text = 'Step back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end